classdef Fiber_photometry_video_sync_tag_app_V1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TimesSlider                 matlab.ui.control.Slider
        TimesSliderLabel            matlab.ui.control.Label
        AdditionOptionPanel         matlab.ui.container.Panel
        TriggerCheckBox             matlab.ui.control.CheckBox
        MarkerCheckBox              matlab.ui.control.CheckBox
        TagtoolPanel                matlab.ui.container.Panel
        SaveTagButton               matlab.ui.control.Button
        ColorDropDown               matlab.ui.control.DropDown
        ColorDropDownLabel          matlab.ui.control.Label
        UITable_Tag                 matlab.ui.control.Table
        DeleteButton                matlab.ui.control.Button
        EditButton                  matlab.ui.control.Button
        AddButton                   matlab.ui.control.Button
        TagTypeDropDown             matlab.ui.control.DropDown
        TagTypeDropDownLabel        matlab.ui.control.Label
        ROItracePanel               matlab.ui.container.Panel
        SmoothCheckBox              matlab.ui.control.CheckBox
        LowPassCutOffSpinner        matlab.ui.control.Spinner
        HzLabel                     matlab.ui.control.Label
        SubtractLowPassCheckBox     matlab.ui.control.CheckBox
        correctionCheckBox          matlab.ui.control.CheckBox
        Zoomed_in_TimeSpinner       matlab.ui.control.Spinner
        sLabel                      matlab.ui.control.Label
        ZoomedinCheckBox            matlab.ui.control.CheckBox
        NormalizeFFCheckBox         matlab.ui.control.CheckBox
        BaselinecorrectionCheckBox  matlab.ui.control.CheckBox
        ROIselectionDropDown        matlab.ui.control.DropDown
        ROIselectionDropDownLabel   matlab.ui.control.Label
        VideoPlayPanel              matlab.ui.container.Panel
        PlayspeedSpinner            matlab.ui.control.Spinner
        PlayspeedSpinnerLabel       matlab.ui.control.Label
        sButton_2                   matlab.ui.control.Button
        sButton                     matlab.ui.control.Button
        PlayPauseButton             matlab.ui.control.Button
        FilePanel                   matlab.ui.container.Panel
        LoadVideoButton             matlab.ui.control.Button
        LoadCSVButton               matlab.ui.control.Button
        UIAxes_Marker_Trigger       matlab.ui.control.UIAxes
        UIAxes_ROI_trace_470_410    matlab.ui.control.UIAxes
        UIAxes_Video                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Raw table and derived vectors
        csvT table = table();           % full CSV
        timeVec double = [];            % seconds, Nx1

        % ROI bookkeeping
        roiCount (1,1) double = 0
        roiIdx410 cell = {}             % per-ROI column indices (410)
        roiIdx470 cell = {}             % per-ROI column indices (470)
        roiNames string = string.empty  % "ROI-1","ROI-2",...

        % Signals (each cell vector length N)
        sig410 cell = {}
        sig470 cell = {}
        sigRatio cell = {}              % 470./410 (raw)
        sigLP cell = {}                 % low-pass
        sigProc cell = {}               % final shown (LP or Raw-LP), optionally ΔF/F%

        % Digital / markers
        digiCols struct = struct('names', strings(0,1), 'data', {{}});

        % Cache for full-range x/y limits
        yFull double = [] % Description

        % --- time/slider state ---
        currentTime double = 0
        timeLineROI matlab.graphics.chart.primitive.Line = matlab.graphics.chart.primitive.Line.empty
        timeLineDIG matlab.graphics.chart.primitive.Line = matlab.graphics.chart.primitive.Line.empty
        isScrubbing logical = false

        % --- video state ---
        vidObj                   % VideoReader
        vidTimer                 % timer object
        isPlaying logical = false
        vidImg                   % image handle in UIAxes_Video
        epsTime double = 1e-3    % small epsilon for end-of-file guard


        % ---- Behavioral tag datastructures ----
        tagsByROI cell = {};         % {roi} -> struct array with fields: id,type,time,color
        nextTagID double = 1;        % auto-increment tag id
        lineTagPrefix char = 'bhvrline-';  % graphics 'Tag' prefix to find/delete lines

        % Remember CSV path for saving tag workbook
        csvFolder char = '';
        csvBase   char = '';

        timeDecimals (1,1) double = 2; % To control the Time decimals number

        % --- smooth playback state ---
        vidFrameRate double = NaN
        lastFrameIdx double = 0          % last decoded frame index (1-based)
        playStartWall            % timer token from tic (untyped)
        playBaseTime double = 0          % time (s) in the recording when Play was pressed
        needsSeek logical = true         % set true whenever we must reposition decoder


    end

    methods (Access = private)

        function [tSec] = parseTimeColumn(app, col1)
            % Accept datetime or duration or text like 0:00:00:00.0500020
            if isdatetime(col1)
                tSec = seconds(col1 - col1(1));
            elseif isduration(col1)
                tSec = seconds(col1) - seconds(col1(1));
            else
                % try to parse as text H:MM:SS:FF.FFFFFF (your example style)
                if iscellstr(col1) || isstring(col1)
                    s = string(col1);
                    % Replace the third colon with '.' if needed, then parse duration
                    s = replace(s, regexpPattern('^([^:]+:[^:]+:[^:]+):'), "$1.");
                    try
                        d = duration(s,'Format','hh:mm:ss.SSSSSSS');
                        tSec = seconds(d) - seconds(d(1));
                    catch
                        error('Unrecognized time format in column 1.');
                    end
                else
                    error('Column 1 must be datetime/duration/text time.');
                end
            end
            tSec = tSec(:);
        end

        function detectROIs(app)
            vnames = string(app.csvT.Properties.VariableNames);

            % Allow: ROI-1, ROI_1, ROI 1 + anything ... + sig_410 / sig_470
            tok410 = regexp(vnames, '^ROI[-_ ]?(\d+).*sig[-_ ]?410$', 'tokens','once','ignorecase');
            tok470 = regexp(vnames, '^ROI[-_ ]?(\d+).*sig[-_ ]?470$', 'tokens','once','ignorecase');

            map410 = containers.Map('KeyType','double','ValueType','double');
            map470 = containers.Map('KeyType','double','ValueType','double');

            for i = 1:numel(vnames)
                if ~isempty(tok410{i})
                    rid = str2double(tok410{i}{1});  map410(rid) = i;
                end
                if ~isempty(tok470{i})
                    rid = str2double(tok470{i}{1});  map470(rid) = i;
                end
            end

            rois = sort(intersect(cell2mat(keys(map410)), cell2mat(keys(map470))));
            app.roiCount = numel(rois);

            app.roiIdx410 = cell(app.roiCount,1);
            app.roiIdx470 = cell(app.roiCount,1);
            app.roiNames  = strings(app.roiCount,1);
            for k = 1:app.roiCount
                rid = rois(k);
                app.roiIdx410{k} = map410(rid);
                app.roiIdx470{k} = map470(rid);
                app.roiNames(k)  = "ROI-" + rid;
            end

            % ---- safely program the dropdown even if empty ----
            oldFcn = app.ROIselectionDropDown.ValueChangedFcn;
            app.ROIselectionDropDown.ValueChangedFcn = [];

            if app.roiCount >= 1
                app.ROIselectionDropDown.Items = string(1:app.roiCount);
                app.ROIselectionDropDown.Value = string(1);
            else
                % When Items is empty, Value MUST be {}
                app.ROIselectionDropDown.Items = {};
                app.ROIselectionDropDown.Value = {};
            end

            app.ROIselectionDropDown.ValueChangedFcn = oldFcn;
        end

        function detectDigitalColumns(app)
            vnames = string(app.csvT.Properties.VariableNames);

            digMask = contains(vnames,"Marker",'ignorecase',true) ...
                |  contains(vnames,"DIO",'ignorecase',true) ...
                |  contains(vnames,"Trigger",'ignorecase',true);

            dNames = vnames(digMask);

            % Ensure struct exists with expected fields
            if isempty(app.digiCols) || ~isstruct(app.digiCols) ...
                    || ~isfield(app.digiCols,'names') || ~isfield(app.digiCols,'data')
                app.digiCols = struct('names', strings(0,1), 'data', {{}});
            end

            app.digiCols.names = dNames(:);                 % column vector
            app.digiCols.data  = cell(numel(dNames),1);
            for i = 1:numel(dNames)
                v = app.csvT.(dNames(i));
                if ~isnumeric(v); v = double(v); end
                v = v(:); v(~isfinite(v)) = 0;
                app.digiCols.data{i} = v ~= 0;             % logical 0/1
            end
        end


        function processSignals(app)
            if app.roiCount==0, return; end

            % Sampling rate and LP cutoff
            dt = median(diff(app.timeVec));
            Fs = 1/max(dt, eps);

            fc = max(1e-3, app.LowPassCutOffSpinner.Value);
            if fc >= Fs/2
                fc = Fs/2 - 1e-3;
                app.LowPassCutOffSpinner.Value = max(fc,1e-3);
            end
            [b,a] = butter(4, fc/(Fs/2), 'low');

            app.sig410 = cell(app.roiCount,1);
            app.sig470 = cell(app.roiCount,1);
            app.sigRatio = cell(app.roiCount,1);
            app.sigLP    = cell(app.roiCount,1);
            app.sigProc  = cell(app.roiCount,1);

            for k = 1:app.roiCount
                s410 = app.csvT{:, app.roiIdx410{k}};
                s470 = app.csvT{:, app.roiIdx470{k}};
                s410 = s410(:); s470 = s470(:);

                % Robust clean
                good = isfinite(app.timeVec) & isfinite(s410) & isfinite(s470);
                s410 = s410(good); s470 = s470(good);
                t    = app.timeVec(good);

                % Store back to common length (pad with NaN if trimmed)
                if numel(t) < numel(app.timeVec)
                    % align on front; you could also reindex, but for plotting it's OK
                    padN = numel(app.timeVec) - numel(t);
                    s410 = [s410; nan(padN,1)];
                    s470 = [s470; nan(padN,1)];
                end

                app.sig410{k} = s410;
                app.sig470{k} = s470;

                if app.correctionCheckBox.Value
                    raw = s470 ./ s410;
                else
                    raw = s470;  % show 470 channel only if correction turned off
                end

                % Low-pass (zero-phase)
                rawLP = filtfilt(b,a, fillmissing(raw,'linear','EndValues','nearest'));

                % Baseline correction on LP
                if app.BaselinecorrectionCheckBox.Value
                    p = polyfit((1:numel(rawLP))', rawLP, 3);
                    rawLP = rawLP - polyval(p,(1:numel(rawLP))');
                end

                if app.SubtractLowPassCheckBox.Value
                    sig = raw - rawLP;    % high-frequency component
                else
                    sig = rawLP;          % smoothed (and baseline corrected)
                end

                if app.NormalizeFFCheckBox.Value
                    base = prctile(sig(~isnan(sig)), 10);
                    if ~isfinite(base) || base==0, base = eps; end
                    sig = (sig - base) ./ base * 100;  % ΔF/F%
                end

                if app.SmoothCheckBox.Value
                    % 'omitnan' avoids NaN padding from earlier alignment from polluting the mean
                    sig = smoothdata(sig, 'movmean', 5, 'omitnan');
                end


                app.sigRatio{k} = raw;
                app.sigLP{k}    = rawLP;
                app.sigProc{k}  = sig;
            end
        end

        function [idx, errMsg] = getSelectedROIIndex(app)
            v = app.ROIselectionDropDown.Value;
            idx = round(str2double(v));
            errMsg = '';

            % Always check a valid numeric index within discovered ROI count
            if isnan(idx) || idx < 1 || idx > app.roiCount
                errMsg = sprintf('ROI %s does not exist. Available ROI(s): 1..%d.', v, app.roiCount);
                idx = NaN;  return
            end

            % Only check signal availability AFTER processing has happened
            if ~isempty(app.sigProc)
                if idx > numel(app.sigProc) || isempty(app.sigProc{idx})
                    errMsg = sprintf('ROI %d has no processed signal.', idx);
                    idx = NaN;
                end
            end
        end

        function plotSelectedROI(app)
            ax = app.UIAxes_ROI_trace_470_410;
            cla(ax); hold(ax,'on'); grid(ax,'on');

            [idx, msg] = app.getSelectedROIIndex();
            if (isstring(msg) && any(strlength(msg) > 0)) || (ischar(msg) && ~isempty(msg)) || isnan(idx)
                return
            end

            y = app.sigProc{idx};

            plot(ax, app.timeVec, y, 'LineWidth', 1.4, 'Color', [0 0 0], 'DisplayName', app.roiNames(idx));  % black

            % Labels
            if app.NormalizeFFCheckBox.Value
                ylabel(ax, '\DeltaF/F (%)');
            elseif app.correctionCheckBox.Value
                ylabel(ax, '470/410 (a.u.)');
            else
                ylabel(ax, '470 (a.u.)');
            end
            xlabel(ax, 'Time (s)');
            title(ax, "ROI trace (" + app.roiNames(idx) + ")");

            % (optional) small legend inside
            lgd = legend(ax, 'Location','northeast', 'Interpreter','none', 'Box','off');
            lgd.AutoUpdate = 'off';

            drawnow limitrate;
            % Full-range Y from current ROI data
            [ymin, ymax] = app.computeYLimits(idx, []);   % full time
            ylim(ax, [ymin ymax]);
            app.yFull = [ymin ymax];                      % refresh cache based on current data

            app.applyZoom();                              % will override if zoom is ON
        end


        function plotDigital(app)
            ax = app.UIAxes_Marker_Trigger;
            cla(ax); hold(ax,'on'); grid(ax,'on');

            n = numel(app.digiCols.names);
            if n==0
                title(ax,'Markers/Triggers (none detected)');
                xlabel(ax,'Time (s)');
                legend(ax,'off');
                ax.YTick = []; ax.YTickLabel = {};
                return
            end

            names = string(app.digiCols.names);
            showMarker  = app.MarkerCheckBox.Value;
            showTrigger = app.TriggerCheckBox.Value;

            % --- selection rules ---
            isMarker  = contains(names,"marker",'ignorecase',true);
            isTrigger = contains(names,"trigger",'ignorecase',true) ...
                | strcmpi(names,"Input_DIO-2") | strcmpi(names,"Input_DIO_2");

            drawMask = false(size(names));
            if showMarker,  drawMask = drawMask | isMarker;  end
            if showTrigger, drawMask = drawMask | isTrigger; end

            % plot selected channels, stacked (bottom=0 .. top=n-1)
            for i = 1:n
                if ~drawMask(i), continue; end
                y = double(app.digiCols.data{i}); y = y(:);
                stairs(ax, app.timeVec, y + (n - i)*1.2, 'LineWidth', 1.2, ...
                    'DisplayName', app.digiCols.names(i));
            end

            % axis cosmetics (keep your settings)
            ax.YLim  = [-0.5, n+0.5];
            ax.YTick = 0:(n-1);

            % your YTickLabel builder (unchanged)
            abbr = strings(n,1);
            for i = 1:n
                nm = lower(string(app.digiCols.names(i)));
                if contains(nm,"trigger") || strcmpi(names(i),"Input_DIO-2")
                    abbr(i) = "Tr";
                elseif contains(nm,["marker_start","start"])
                    abbr(i) = "St";
                elseif contains(nm,["marker_stop","stop"])
                    abbr(i) = "Sp";
                else
                    tok = regexp(app.digiCols.names(i), '[A-Za-z]+', 'match');
                    if ~isempty(tok)
                        abbr(i) = extractBefore(tok{1}, min(3, strlength(tok{1})));
                    else
                        abbr(i) = "Ch";
                    end
                end
            end
            labels = compose("%d%s", (0:n-1).', flip(abbr));
            ax.YTickLabel = cellstr(labels);

            xlabel(ax,'Time (s)');
            title(ax,'Markers / Trigger');

            lgd = legend(ax, 'Location','northeast', 'Interpreter','none', 'Box','off');
            lgd.AutoUpdate = 'off';

            if ~isempty(app.timeVec)
                xlim(ax, xlim(app.UIAxes_ROI_trace_470_410));
            end
        end


        function applyZoom(app, tCenter)
            if nargin < 2, tCenter = app.currentTime; end

            % If zoom is OFF and we are playing, don’t keep resetting limits.
            if ~app.ZoomedinCheckBox.Value && app.isPlaying
                return
            end

            ax = app.UIAxes_ROI_trace_470_410;
            yl = ylim(ax);
            set(findobj(ax.Children,'-regexp','Tag',['^' app.lineTagPrefix]), 'YData', [yl(1) yl(2)]);
            if nargin < 2, tCenter = app.currentTime; end

            [selIdx, msg] = app.getSelectedROIIndex();
            if (isstring(msg) && any(strlength(msg) > 0)) || (ischar(msg) && ~isempty(msg)) || isempty(app.timeVec)
                return
            end

            if ~app.ZoomedinCheckBox.Value
                % Full-range X and recompute Y from *current* data
                t1 = 0; t2 = max(app.timeVec);
                xlim(ax, [t1, t2]);
                [ymin, ymax] = app.computeYLimits(selIdx, [t1 t2]);
                ylim(ax, [ymin ymax]);
                app.yFull = [ymin ymax];  % keep cache in sync

                % Sync the cursor line and digital axis
                if ~(isempty(app.timeLineROI) || ~isvalid(app.timeLineROI) || ~isgraphics(app.timeLineROI))
                    yl = ylim(ax); app.timeLineROI.YData = [yl(1) yl(2)];
                end
                xlim(app.UIAxes_Marker_Trigger, [t1, t2]);
                return
            end

            % ---- Zoom ON ----
            W  = max(1, app.Zoomed_in_TimeSpinner.Value);
            hw = W/2;
            t1 = max(0, tCenter - hw);
            t2 = min(max(app.timeVec), tCenter + hw);
            if (t2 - t1) < W
                if t1 <= 0,                t2 = min(max(app.timeVec), t1 + W); end
                if t2 >= max(app.timeVec), t1 = max(0,            t2 - W); end
            end
            xlim(ax, [t1 t2]);

            [ymin, ymax] = app.computeYLimits(selIdx, [t1 t2]);
            ylim(ax, [ymin ymax]);

            if ~(isempty(app.timeLineROI) || ~isvalid(app.timeLineROI) || ~isgraphics(app.timeLineROI))
                yl = ylim(ax);
                app.timeLineROI.YData = [yl(1) yl(2)];
                app.timeLineROI.XData = [tCenter tCenter];
            end

            % Keep digital axis in sync with ROI x-limits
            xlim(app.UIAxes_Marker_Trigger, [t1, t2]);
        end

        function reprocessAndRedraw(app)
            if isempty(app.csvT), return; end
            app.processSignals();
            app.plotSelectedROI();
            app.plotDigital();
            app.updateTime(app.currentTime);   % <- ensure cursor is recreated after cla
        end


        function updateTime(app, t)
            if isempty(app.timeVec), return; end
            t = max(app.TimesSlider.Limits(1), min(t, app.TimesSlider.Limits(2)));
            app.currentTime = t;

            if ~app.isScrubbing
                app.TimesSlider.Value = t;
            end
            app.TimesSliderLabel.Text = sprintf('Time: %.2f s', t);

            % ----- ROI axis cursor -----
            ax1 = app.UIAxes_ROI_trace_470_410;
            needNewROI = isempty(app.timeLineROI) || ~isvalid(app.timeLineROI) || ~isgraphics(app.timeLineROI);
            if needNewROI
                app.timeLineROI = plot(ax1, [t t], ylim(ax1), 'k--', 'LineWidth', 1.2, ...
                    'HandleVisibility','off', 'HitTest','off', 'PickableParts','none');
            else
                app.timeLineROI.XData = [t t];
                yl = ylim(ax1);
                app.timeLineROI.YData = [yl(1) yl(2)];
            end

            % ----- Digital axis cursor -----
            ax2 = app.UIAxes_Marker_Trigger;
            needNewDIG = isempty(app.timeLineDIG) || ~isvalid(app.timeLineDIG) || ~isgraphics(app.timeLineDIG);
            if needNewDIG
                app.timeLineDIG = plot(ax2, [t t], ylim(ax2), 'k--', 'LineWidth', 1.2, ...
                    'HandleVisibility','off', 'HitTest','off', 'PickableParts','none');
            else
                app.timeLineDIG.XData = [t t];
                yl2 = ylim(ax2);
                app.timeLineDIG.YData = [yl2(1) yl2(2)];
            end
            % Center zoom if enabled
            app.applyZoom(t);


            % Video sync policy:
            % - If playing: set a seek flag and let the timer reposition once.
            % - If paused: do a precise seek to show the exact frame now.
            if ~isempty(app.vidObj)
                if app.isPlaying
                    app.needsSeek = true;
                else
                    app.seekAndShowFrame(t);
                end
            end
        end


        function moveTime(app, dt)
            if isempty(app.timeVec), return; end
            app.updateTime(app.TimesSlider.Value + dt);
        end

        function onVideoTimer(app)
            if isempty(app.vidObj), return; end

            % Use cached fps if available
            fr = app.vidFrameRate;
            if ~isfinite(fr) || fr <= 0
                fr = max(app.vidObj.FrameRate, eps);
            end

            % Target playback time from wall clock * play speed
            elapsedWall = toc(app.playStartWall);
            targetTime  = app.playBaseTime + app.PlayspeedSpinner.Value * elapsedWall;

            % Respect both video and signal limits
            tMaxVideo  = max(0, app.vidObj.Duration - 1/fr - app.epsTime);
            tMaxSlider = app.TimesSlider.Limits(2);
            tMax = min(tMaxVideo, tMaxSlider);
            if targetTime > tMax, targetTime = tMax; end

            % Desired frame index (1-based)
            targetIdx = max(1, floor(targetTime * fr) + 1);

            % Seek once if needed (e.g., user scrubbed/jumped)
            if app.needsSeek
                app.vidObj.CurrentTime = max(0, (targetIdx-1)/fr);
                app.lastFrameIdx = targetIdx - 1;
                app.needsSeek = false;
            end

            % Decode forward until we reach targetIdx (drop frames if behind)
            decoded = false;
            frame = [];
            while app.lastFrameIdx < targetIdx && hasFrame(app.vidObj)
                frame = readFrame(app.vidObj);   % decode & drop until the target
                app.lastFrameIdx = app.lastFrameIdx + 1;
                decoded = true;
            end

            % If we decoded at least one frame (or we’re exactly on target), show it
            if decoded
                if isempty(app.vidImg) || ~isvalid(app.vidImg)
                    app.vidImg = image(app.UIAxes_Video, frame);
                    axis(app.UIAxes_Video, 'image');
                else
                    app.vidImg.CData = frame;
                end
                % Update logical time from frame index (no extra seeking)
                tNow = (app.lastFrameIdx - 1) / fr;
                app.updateTime(tNow);
            end

            % Stop at the end
            if targetTime >= tMax || ~hasFrame(app.vidObj)
                try, stop(app.vidTimer); end %#ok<TRYNC>
                app.isPlaying = false;
                app.PlayPauseButton.Text = 'Play/Pause';
            end

            drawnow limitrate   % throttle UI repaints
        end


        function ok = seekAndShowFrame(app, t)
            ok = false;
            if isempty(app.vidObj), return; end

            fr = app.vidFrameRate;
            if ~isfinite(fr) || fr <= 0
                fr = max(app.vidObj.FrameRate, eps);
            end

            % Clamp to valid range
            tClamp = min(max(t, 0), max(0, app.vidObj.Duration - 1/fr - app.epsTime));

            app.vidObj.CurrentTime = tClamp;
            if hasFrame(app.vidObj)
                frame = readFrame(app.vidObj);
                if isempty(app.vidImg) || ~isvalid(app.vidImg)
                    app.vidImg = image(app.UIAxes_Video, frame);
                    axis(app.UIAxes_Video, 'image');
                else
                    app.vidImg.CData = frame;
                end
                % Keep decoder index synced with display
                app.lastFrameIdx = floor(tClamp * fr) + 1;
                ok = true;
            end
        end


        function setXLimBoth(app, xlims)
            % xlims = [t1 t2]
            xlim(app.UIAxes_ROI_trace_470_410, xlims);
            xlim(app.UIAxes_Marker_Trigger,   xlims);
            app.UIAxes_ROI_trace_470_410.XLimMode = 'manual';
            app.UIAxes_Marker_Trigger.XLimMode   = 'manual';
        end


        function [ymin, ymax] = computeYLimits(app, roiIdx, tRange)
            y = app.sigProc{roiIdx};
            t = app.timeVec;

            % Time mask (optional)
            if nargin >= 3 && ~isempty(tRange)
                idx = t >= tRange(1) & t <= tRange(2);
                y = y(idx);
            end

            % Robust min/max
            ymin = min(y, [], 'omitnan');
            ymax = max(y, [], 'omitnan');

            % Fallbacks & padding
            if isempty(ymin) || isempty(ymax) || ~isfinite(ymin) || ~isfinite(ymax) || ymin == ymax
                if ~isempty(app.yFull) && all(isfinite(app.yFull))
                    ymin = app.yFull(1);
                    ymax = app.yFull(2);
                    if ymin == ymax
                        ymin = ymin - 0.5; ymax = ymax + 0.5;
                    end
                else
                    ymin = -0.5; ymax = 0.5;
                end
            else
                % Add ~5% headroom
                pad = 0.05 * max(1, ymax - ymin);
                ymin = ymin - pad;
                ymax = ymax + pad;
            end
        end
        function initTagStore(app)
            % Call after detectROIs() once CSV is loaded
            app.tagsByROI = cell(max(1, app.roiCount), 1);
            for r = 1:numel(app.tagsByROI)
                app.tagsByROI{r} = struct('id',{},'type',{},'time',{},'color',{});
            end
            app.nextTagID = 1;
            app.refreshTagTable();   % empty table
        end

        function c = colorSpecFromName(~, name)
            % Accepts Blue/Green/Red/Grey/Magenta/Cyan/Yellow (case-insensitive)
            n = lower(string(name));
            switch n
                case "blue",     c = [0 0.4470 0.7410];
                case "green",    c = [0.4660 0.6740 0.1880];
                case "red",      c = [0.8500 0.3250 0.0980];
                case {"grey","gray"}, c = [0.5 0.5 0.5];
                case "magenta",  c = [0.4940 0.1840 0.5560];
                case "cyan",     c = [0.3010 0.7450 0.9330];
                case "yellow",   c = [0.9290 0.6940 0.1250];
                otherwise,       c = [0 0 0];
            end
        end

        function c = defaultColorForTag(app, tagType)
            % 1..5 -> Blue, Green, Red, Grey, Magenta
            switch tagType
                case 1, c = app.colorSpecFromName("Blue");
                case 2, c = app.colorSpecFromName("Green");
                case 3, c = app.colorSpecFromName("Red");
                case 4, c = app.colorSpecFromName("Grey");
                case 5, c = app.colorSpecFromName("Magenta");
                otherwise, c = [0 0 0];
            end
        end

        function refreshTagTable(app)
            rows = {};
            for r = 1:numel(app.tagsByROI)
                arr = app.tagsByROI{r};
                if isempty(arr), continue; end
                ids   = [arr.id]';
                types = [arr.type]';
                times = [arr.time]';
                rois  = r*ones(numel(arr),1);

                % 显示格式：编号/类型/ROI号为整数；Time 按 app.timeDecimals 位显示
                fmtTime = "%." + string(app.timeDecimals) + "f";
                col1 = cellstr(compose("%.0f", ids));
                col2 = cellstr(compose("%.0f", types));
                col3 = cellstr(compose(fmtTime, times));
                col4 = cellstr(compose("%.0f", rois));

                rows = [rows; [col1 col2 col3 col4]]; %#ok<AGROW>
            end

            if isempty(rows)
                app.UITable_Tag.Data = cell(0,4);
            else
                app.UITable_Tag.Data = rows;   % 用字符串显示，控制住小数位
            end

            % 若不希望用户在表里直接编辑（避免破坏内部结构），建议：
            app.UITable_Tag.ColumnEditable = [false false false false];
        end

        function clearBehaviorLinesOnAxis(app)
            ax = app.UIAxes_ROI_trace_470_410;
            % Delete previously drawn tag lines
            if ~isempty(ax.Children)
                toKill = findobj(ax.Children, '-regexp','Tag',['^' app.lineTagPrefix]);
                delete(toKill);
            end
        end

        function drawBehaviorLinesForSelectedROI(app)
            if isempty(app.timeVec) || app.roiCount==0, return; end
            % Draw only for the currently selected ROI
            [roiIdx, msg] = app.getSelectedROIIndex();
            if (isstring(msg) && any(strlength(msg) > 0)) || (ischar(msg) && ~isempty(msg)) || isnan(roiIdx)
                return
            end
            app.clearBehaviorLinesOnAxis();

            ax = app.UIAxes_ROI_trace_470_410;
            arr = app.tagsByROI{roiIdx};
            if isempty(arr), return; end

            yl = ylim(ax);
            for k = 1:numel(arr)
                plot(ax, [arr(k).time arr(k).time], yl, '--', ...
                    'Color', arr(k).color, 'LineWidth', 1.2, ...
                    'Tag', [app.lineTagPrefix num2str(arr(k).id)], ...
                    'HandleVisibility','off', 'HitTest','off', 'PickableParts','none');
            end
        end

        function addBehaviorTag(app, roiIdx, tagType, t, color)
            if roiIdx < 1 || roiIdx > numel(app.tagsByROI), return; end
            rec.id    = app.nextTagID;  app.nextTagID = app.nextTagID + 1;
            rec.type  = tagType;
            rec.time  = t;
            rec.color = color;
            app.tagsByROI{roiIdx}(end+1) = rec;

            app.refreshTagTable();

            % If adding to the currently displayed ROI, draw immediately
            [selIdx, ~] = app.getSelectedROIIndex();
            if ~isnan(selIdx) && selIdx == roiIdx
                ax = app.UIAxes_ROI_trace_470_410;
                yl = ylim(ax);
                plot(ax, [t t], yl, '--', 'Color', color, 'LineWidth', 1.2, ...
                    'Tag', [app.lineTagPrefix num2str(rec.id)], ...
                    'HandleVisibility','off', 'HitTest','off', 'PickableParts','none');
            end
        end

        function removeBehaviorTagByID(app, idToRemove)
            % Find which ROI contains this id
            roiFound = NaN; idxInArr = NaN;
            for r = 1:numel(app.tagsByROI)
                ids = [app.tagsByROI{r}.id];
                hit = find(ids==idToRemove, 1, 'first');
                if ~isempty(hit)
                    roiFound = r; idxInArr = hit; break
                end
            end
            if isnan(roiFound), return; end

            % Remove from storage
            arr = app.tagsByROI{roiFound};
            arr(idxInArr) = [];
            app.tagsByROI{roiFound} = arr;

            % Remove any drawn line for this id on the ROI axis
            ax = app.UIAxes_ROI_trace_470_410;
            h = findobj(ax.Children, 'Tag', [app.lineTagPrefix num2str(idToRemove)]);
            delete(h);

            app.refreshTagTable();
        end

        function saveTagsToXLSX(app)
            if isempty(app.csvBase)
                % Default to uiputfile if we don't know the CSV path
                [f,p] = uiputfile('*.xlsx','Save Tag Workbook As','TagTime.xlsx');
                if isequal(f,0), return; end
                outPath = fullfile(p,f);
            else
                outPath = fullfile(app.csvFolder, [app.csvBase '_TagTime.xlsx']);
            end

            % Build and write sheets: ROI#_Tag#
            for r = 1:numel(app.tagsByROI)
                for tag = 1:5  % 1..5
                    arr = app.tagsByROI{r};
                    times = [arr([arr.type]==tag).time]'; % column
                    if isempty(times)
                        % write a header with no rows so the sheet exists (optional)
                        T = table('Size',[0 1],'VariableTypes',"double",'VariableNames',"Time_s");
                    else
                        T = table(times, 'VariableNames',"Time_s");
                    end
                    sheetName = sprintf('ROI%d_Tag%d', r, tag);
                    try
                        writetable(T, outPath, 'Sheet', sheetName, 'WriteMode', 'overwritesheet');
                    catch ME
                        uialert(app.UIFigure, "Failed to write sheet " + sheetName + ": " + ME.message, 'Error');
                        return
                    end
                end
            end
            uialert(app.UIFigure, "Saved tags to " + outPath, 'Saved');
        end

        function saveTagPNGs(app)
            % Guard: need a CSV base/folder to name/save
            if isempty(app.csvBase) || isempty(app.csvFolder)
                uialert(app.UIFigure, 'Load a CSV first so I know where to save PNGs.', 'No CSV');
                return
            end

            % Mirror the current x-limits of your on-screen ROI axis (zoom/full view)
            xlims = xlim(app.UIAxes_ROI_trace_470_410);
            curT  = app.currentTime;  % draw a vertical cursor for reference

            % Build a y-label string consistent with the UI settings
            if app.NormalizeFFCheckBox.Value
                ylab = '\DeltaF/F (%)';
            elseif app.correctionCheckBox.Value
                ylab = '470/410 (a.u.)';
            else
                ylab = '470 (a.u.)';
            end

            nR = max(1, app.roiCount);
            for r = 1:nR
                % ---- Create an offscreen figure with two stacked axes ----
                fig = figure('Visible','off','Position',[100 100 1600 420],'Color','w');
                tl = tiledlayout(fig, 3, 1, 'TileSpacing','compact', 'Padding','compact');

                % ===================== TOP: ROI trace ======================
                ax1 = nexttile(tl, [2 1]); hold(ax1,'on'); grid(ax1,'on');
                t = app.timeVec(:);
                y = app.sigProc{r};   % processed signal as shown in the app
                plot(ax1, t, y, 'k', 'LineWidth', 1.2);
                xlim(ax1, xlims);

                % Robust y-limits (reuse your helper for consistency)
                [ymin, ymax] = app.computeYLimits(r, xlims);
                ylim(ax1, [ymin ymax]);

                % Draw behavior tag lines for this ROI
                if r <= numel(app.tagsByROI) && ~isempty(app.tagsByROI{r})
                    yl = ylim(ax1);
                    for k = 1:numel(app.tagsByROI{r})
                        tt  = app.tagsByROI{r}(k).time;
                        col = app.tagsByROI{r}(k).color;
                        plot(ax1, [tt tt], yl, '--', 'Color', col, 'LineWidth', 1.2, ...
                            'HandleVisibility','off', 'HitTest','off');
                    end
                end

                % Draw current-time cursor (dashed black)
                yl = ylim(ax1);
                plot(ax1, [curT curT], yl, 'k--', 'LineWidth', 1.0, 'HandleVisibility','off');

                ylabel(ax1, ylab);
                xlabel(ax1, 'Time (s)');
                title(ax1, "ROI trace (" + app.roiNames(r) + ")", 'Interpreter','none');

                % ================= BOTTOM: Markers / Trigger ===============
                ax2 = nexttile(tl); hold(ax2,'on'); grid(ax2,'on');

                names = string(app.digiCols.names);
                nD    = numel(names);

                if nD > 0
                    % Selection rules must match on-screen checkboxes
                    showMarker  = app.MarkerCheckBox.Value;
                    showTrigger = app.TriggerCheckBox.Value;

                    isMarker  = contains(names,"marker",'ignorecase',true);
                    isTrigger = contains(names,"trigger",'ignorecase',true) ...
                        | strcmpi(names,"Input_DIO-2") | strcmpi(names,"Input_DIO_2");

                    drawMask = false(size(names));
                    if showMarker,  drawMask = drawMask | isMarker;  end
                    if showTrigger, drawMask = drawMask | isTrigger; end

                    % Plot selected digital channels stacked
                    for i = 1:nD
                        if ~drawMask(i), continue; end
                        yy = double(app.digiCols.data{i}); yy = yy(:);
                        stairs(ax2, t, yy + (nD - i)*1.2, 'LineWidth', 1.2, ...
                            'DisplayName', names(i));
                    end

                    % Cosmetics similar to UI
                    ax2.YLim  = [-0.5, nD+0.5];
                    ax2.YTick = 0:(nD-1);

                    abbr = strings(nD,1);
                    for i = 1:nD
                        nm = lower(string(names(i)));
                        if contains(nm,"trigger") || strcmpi(names(i),"Input_DIO-2")
                            abbr(i) = "Tr";
                        elseif contains(nm,["marker_start","start"])
                            abbr(i) = "St";
                        elseif contains(nm,["marker_stop","stop"])
                            abbr(i) = "Sp";
                        else
                            tok = regexp(names(i), '[A-Za-z]+', 'match');
                            if ~isempty(tok)
                                abbr(i) = extractBefore(tok{1}, min(3, strlength(tok{1})));
                            else
                                abbr(i) = "Ch";
                            end
                        end
                    end
                    labels = compose("%d%s", (0:nD-1).', flip(abbr));
                    ax2.YTickLabel = cellstr(labels);
                else
                    title(ax2,'Markers/Triggers (none detected)');
                    ax2.YTick = []; ax2.YTickLabel = {};
                end

                xlim(ax2, xlims);
                xlabel(ax2,'Time (s)');
                title(ax2,'Markers / Trigger');

                % Current-time cursor on digital axis
                yl2 = ylim(ax2);
                plot(ax2, [curT curT], yl2, 'k--', 'LineWidth', 1.0, 'HandleVisibility','off');

                % ---- Export & close ----
                pngPath = fullfile(app.csvFolder, sprintf('%s_ROI_%d_Tag_marker.png', app.csvBase, r));
                try
                    exportgraphics(fig, pngPath, 'Resolution', 150);  % hi-res but lightweight
                catch ME
                    close(fig);
                    uialert(app.UIFigure, "Failed to save PNG for ROI " + r + ": " + ME.message, 'Error');
                    return
                end
                close(fig);
            end

            uialert(app.UIFigure, "Saved ROI tag PNG(s) to folder: " + string(app.csvFolder), 'Saved');
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadCSVButton
        function LoadCSVButtonPushed(app, event)
            [f,p] = uigetfile('*.csv', 'Select fiber photometry CSV');
            if isequal(f,0), return; end
            try
                app.csvT = readtable(fullfile(p,f));
                app.csvFolder = p;
                [~, base, ~] = fileparts(f);
                app.csvBase = base;
            catch ME
                uialert(app.UIFigure, "Failed to read CSV: " + ME.message, 'Error'); return;
            end

            if width(app.csvT) < 3
                uialert(app.UIFigure, 'CSV must have at least 3 columns (Time + channels).', 'Error'); return;
            end

            % Time column must be first
            try
                app.timeVec = app.parseTimeColumn(app.csvT{:,1});
            catch ME
                uialert(app.UIFigure, "Time column parse error: " + ME.message, 'Error'); return;
            end

            % Detect ROIs and digital signals
            app.detectROIs();
            app.initTagStore();
            if app.roiCount==0
                uialert(app.UIFigure, ...
                    'No ROI columns found. Expect headers like "ROI-# ... sig_410" and "ROI-# ... sig_470".', ...
                    'Error');
                return;
            end
            app.detectDigitalColumns();

            % Process and plot
            app.reprocessAndRedraw();

            % ---- init time slider from data ----
            if ~isempty(app.timeVec)
                app.TimesSlider.Limits = [0, max(app.timeVec)];
                app.TimesSlider.Value  = 0;
                app.currentTime        = 0;

                % Make both axes share the same full range now
                app.setXLimBoth([0, max(app.timeVec)]);

                app.updateTime(0);          % draws cursors & centers zoom if enabled

            end
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
            [f,p] = uigetfile({'*.avi;*.mp4;*.mov','Video Files'}, 'Select video');
            if isequal(f,0), return; end

            % Clean up prior timer if any
            if ~isempty(app.vidTimer) && isvalid(app.vidTimer)
                try stop(app.vidTimer); delete(app.vidTimer); end
            end
            app.isPlaying = false;
            app.PlayPauseButton.Text = 'Play/Pause';

            % Open video
            try
                app.vidObj = VideoReader(fullfile(p,f));
            catch ME
                uialert(app.UIFigure, "Failed to read video: " + ME.message, 'Error');
                app.vidObj = [];
                return;
            end

            % Cache fps and initialize playback state
            app.vidFrameRate = max(app.vidObj.FrameRate, eps);
            app.lastFrameIdx = 0;
            app.needsSeek    = true;

            % Show first frame at t=0 precisely (paused preview)
            app.seekAndShowFrame(0);

            % Create a timer that ticks ~once per video frame
            app.vidTimer = timer('ExecutionMode','fixedRate', ...
                'Period', 1 / max(app.vidFrameRate, 30), ...
                'TimerFcn', @(~,~) app.onVideoTimer());

        end

        % Button pushed function: SaveTagButton
        function SaveTagButtonPushed(app, event)
            app.saveTagsToXLSX();
            app.saveTagPNGs();
        end

        % Button pushed function: PlayPauseButton
        function PlayPauseButtonPushed(app, event)
            if isempty(app.vidObj)
                uialert(app.UIFigure,'Load a video first.','Info');
                return;
            end

            % (Re)create timer if needed
            if isempty(app.vidTimer) || ~isvalid(app.vidTimer)
                app.vidTimer = timer('ExecutionMode','fixedRate', ...
                    'Period', 1 / max(app.vidFrameRate, 30), ...
                    'TimerFcn', @(~,~) app.onVideoTimer());
            end

            if app.isPlaying
                try stop(app.vidTimer); end %#ok<TRYNC>
                app.isPlaying = false;
                app.PlayPauseButton.Text = 'Play';
            else
                % Initialize wall clock and base time for smooth playback
                app.playBaseTime = app.currentTime;   % where in the recording we start
                app.playStartWall = tic;              % wall clock zero
                app.needsSeek = true;                 % single reposition on first tick
                start(app.vidTimer);
                app.isPlaying = true;
                app.PlayPauseButton.Text = 'Pause';
            end

        end

        % Value changed function: PlayspeedSpinner
        function PlayspeedSpinnerValueChanged(app, event)
            % Clamp to spinner limits in case user types a value
            lim = app.PlayspeedSpinner.Limits;
            v = min(max(app.PlayspeedSpinner.Value, lim(1)), lim(2));
            if v ~= app.PlayspeedSpinner.Value
                app.PlayspeedSpinner.Value = v;
            end
            % No more work needed: onVideoTimer reads this live.

        end

        % Button pushed function: sButton
        function sButtonPushed(app, event)
            app.moveTime(-5);
        end

        % Button pushed function: sButton_2
        function sButton_2Pushed(app, event)
            app.moveTime(+5);
        end

        % Value changed function: ROIselectionDropDown
        function ROIselectionDropDownValueChanged(app, event)
            % Draw the selected ROI only and restore the time cursor
            app.plotSelectedROI();
            app.updateTime(app.currentTime);
            app.drawBehaviorLinesForSelectedROI();
        end

        % Value changed function: BaselinecorrectionCheckBox
        function BaselinecorrectionCheckBoxValueChanged(app, event)
            app.reprocessAndRedraw();
        end

        % Value changed function: NormalizeFFCheckBox
        function NormalizeFFCheckBoxValueChanged(app, event)
            app.reprocessAndRedraw();
        end

        % Value changed function: SubtractLowPassCheckBox
        function SubtractLowPassCheckBoxValueChanged(app, event)
            app.reprocessAndRedraw();
        end

        % Value changed function: ZoomedinCheckBox
        function ZoomedinCheckBoxValueChanged(app, event)
            app.applyZoom();

        end

        % Value changed function: LowPassCutOffSpinner
        function LowPassCutOffSpinnerValueChanged(app, event)
            app.reprocessAndRedraw();
        end

        % Value changed function: Zoomed_in_TimeSpinner
        function Zoomed_in_TimeSpinnerValueChanged(app, event)
            app.applyZoom();

        end

        % Value changed function: MarkerCheckBox
        function MarkerCheckBoxValueChanged(app, event)
            app.plotDigital();

        end

        % Value changed function: TriggerCheckBox
        function TriggerCheckBoxValueChanged(app, event)
            app.plotDigital();

        end

        % Value changed function: TagTypeDropDown
        function TagTypeDropDownValueChanged(app, event)
            t = str2double(app.TagTypeDropDown.Value);
            % Snap the Color dropdown to the canonical color for this type
            switch t
                case 1, app.ColorDropDown.Value = 'Blue';
                case 2, app.ColorDropDown.Value = 'Green';
                case 3, app.ColorDropDown.Value = 'Red';
                case 4, app.ColorDropDown.Value = 'Grey';
                case 5, app.ColorDropDown.Value = 'Magenta';
            end

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if isempty(app.timeVec) || app.roiCount==0, return; end
            [roiIdx, msg] = app.getSelectedROIIndex();
            if (isstring(msg) && any(strlength(msg) > 0)) || (ischar(msg) && ~isempty(msg)) || isnan(roiIdx)
                return
            end

            % Tag type from dropdown (ensure it contains 1..5 in the UI)
            tagType = str2double(app.TagTypeDropDown.Value);
            if isnan(tagType) || tagType < 1, tagType = 1; end

            % Color: if user picked nothing special, use default per tag type
            colname = app.ColorDropDown.Value;
            if isempty(colname)
                col = app.defaultColorForTag(tagType);
            else
                col = app.colorSpecFromName(colname);
            end

            t = app.currentTime;   % seconds
            app.addBehaviorTag(roiIdx, tagType, t, col);

            % Keep the cursor & zoom logic intact
            app.applyZoom(app.currentTime);
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            data = app.UITable_Tag.Data;
            if isempty(data), return; end

            % If a cell is selected, use its row; otherwise pick the last row
            sel = app.UITable_Tag.Selection;
            if ~isempty(sel)
                row = sel(1);
            else
                row = size(data,1);
            end

            idToRemove = data{row,1};   % 'Number' column is our unique id
            if isempty(idToRemove) || ~isnumeric(idToRemove), return; end

            app.removeBehaviorTagByID(idToRemove);

            % Redraw lines for the currently viewed ROI
            app.drawBehaviorLinesForSelectedROI();
        end

        % Value changed function: TimesSlider
        function TimesSliderValueChanged(app, event)
            if isempty(app.timeVec), return; end
            app.updateTime(app.TimesSlider.Value);
        end

        % Value changing function: TimesSlider
        function TimesSliderValueChanging(app, event)
            if isempty(app.timeVec), return; end
            app.isScrubbing = true;
            app.updateTime(event.Value);
            app.isScrubbing = false;
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;

        end

        % Value changed function: SmoothCheckBox
        function SmoothCheckBoxValueChanged(app, event)
            app.reprocessAndRedraw();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Video = uiaxes(app.UIFigure);
            title(app.UIAxes_Video, 'Title')
            zlabel(app.UIAxes_Video, 'Z')
            app.UIAxes_Video.XLim = [0 1];
            app.UIAxes_Video.YLim = [0 1];
            app.UIAxes_Video.ZLim = [0 1];
            app.UIAxes_Video.XTick = [];
            app.UIAxes_Video.YTick = [];
            app.UIAxes_ROI_trace_470_410 = uiaxes(app.UIFigure);
            title(app.UIAxes_ROI_trace_470_410, 'Title')
            xlabel(app.UIAxes_ROI_trace_470_410, 'X')
            ylabel(app.UIAxes_ROI_trace_470_410, 'Y')
            zlabel(app.UIAxes_ROI_trace_470_410, 'Z')
            app.UIAxes_Marker_Trigger = uiaxes(app.UIFigure);
            title(app.UIAxes_Marker_Trigger, 'Title')
            xlabel(app.UIAxes_Marker_Trigger, 'X')
            ylabel(app.UIAxes_Marker_Trigger, 'Marker and Trigger')
            zlabel(app.UIAxes_Marker_Trigger, 'Z')
            app.FilePanel = uipanel(app.UIFigure);
            app.FilePanel.Title = 'File';
            app.LoadCSVButton = uibutton(app.FilePanel, 'push');
            app.LoadCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSVButtonPushed, true);
            app.LoadCSVButton.Text = 'Load CSV';
            app.LoadVideoButton = uibutton(app.FilePanel, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.VideoPlayPanel = uipanel(app.UIFigure);
            app.VideoPlayPanel.Title = 'Video Play';
            app.PlayPauseButton = uibutton(app.VideoPlayPanel, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPauseButtonPushed, true);
            app.PlayPauseButton.Text = 'Play/Pause';
            app.sButton = uibutton(app.VideoPlayPanel, 'push');
            app.sButton.ButtonPushedFcn = createCallbackFcn(app, @sButtonPushed, true);
            app.sButton.Text = '<< 5s';
            app.sButton_2 = uibutton(app.VideoPlayPanel, 'push');
            app.sButton_2.ButtonPushedFcn = createCallbackFcn(app, @sButton_2Pushed, true);
            app.sButton_2.Text = '5s >>';
            app.PlayspeedSpinnerLabel = uilabel(app.VideoPlayPanel);
            app.PlayspeedSpinnerLabel.Text = 'Playspeed';
            app.PlayspeedSpinner = uispinner(app.VideoPlayPanel);
            app.PlayspeedSpinner.Step = 0.25;
            app.PlayspeedSpinner.Limits = [0.25 4];
            app.PlayspeedSpinner.ValueChangedFcn = createCallbackFcn(app, @PlayspeedSpinnerValueChanged, true);
            app.PlayspeedSpinner.Value = 1;
            app.ROItracePanel = uipanel(app.UIFigure);
            app.ROItracePanel.Title = 'ROI trace';
            app.ROIselectionDropDownLabel = uilabel(app.ROItracePanel);
            app.ROIselectionDropDownLabel.Text = 'ROI selection';
            app.ROIselectionDropDown = uidropdown(app.ROItracePanel);
            app.ROIselectionDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.ROIselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIselectionDropDownValueChanged, true);
            app.ROIselectionDropDown.Value = '1';
            app.BaselinecorrectionCheckBox = uicheckbox(app.ROItracePanel);
            app.BaselinecorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselinecorrectionCheckBoxValueChanged, true);
            app.BaselinecorrectionCheckBox.Text = 'Baseline correction';
            app.NormalizeFFCheckBox = uicheckbox(app.ROItracePanel);
            app.NormalizeFFCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeFFCheckBoxValueChanged, true);
            app.NormalizeFFCheckBox.Text = 'Normalize (ΔF/F%)';
            app.NormalizeFFCheckBox.Value = true;
            app.ZoomedinCheckBox = uicheckbox(app.ROItracePanel);
            app.ZoomedinCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZoomedinCheckBoxValueChanged, true);
            app.ZoomedinCheckBox.Text = 'Zoomed in';
            app.sLabel = uilabel(app.ROItracePanel);
            app.sLabel.Text = 's';
            app.Zoomed_in_TimeSpinner = uispinner(app.ROItracePanel);
            app.Zoomed_in_TimeSpinner.Step = 30;
            app.Zoomed_in_TimeSpinner.Limits = [0 3600];
            app.Zoomed_in_TimeSpinner.ValueChangedFcn = createCallbackFcn(app, @Zoomed_in_TimeSpinnerValueChanged, true);
            app.Zoomed_in_TimeSpinner.Value = 60;
            app.correctionCheckBox = uicheckbox(app.ROItracePanel);
            app.correctionCheckBox.Text = '470/410 correction';
            app.correctionCheckBox.Value = true;
            app.SubtractLowPassCheckBox = uicheckbox(app.ROItracePanel);
            app.SubtractLowPassCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractLowPassCheckBoxValueChanged, true);
            app.SubtractLowPassCheckBox.Text = 'Subtract Low-Pass';
            app.HzLabel = uilabel(app.ROItracePanel);
            app.HzLabel.Text = 'Hz';
            app.LowPassCutOffSpinner = uispinner(app.ROItracePanel);
            app.LowPassCutOffSpinner.Limits = [0 30];
            app.LowPassCutOffSpinner.ValueChangedFcn = createCallbackFcn(app, @LowPassCutOffSpinnerValueChanged, true);
            app.LowPassCutOffSpinner.Value = 5;
            app.SmoothCheckBox = uicheckbox(app.ROItracePanel);
            app.SmoothCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothCheckBoxValueChanged, true);
            app.SmoothCheckBox.Text = 'Smooth';
            app.SmoothCheckBox.Value = true;
            app.TagtoolPanel = uipanel(app.UIFigure);
            app.TagtoolPanel.Title = 'Tag tool';
            app.TagTypeDropDownLabel = uilabel(app.TagtoolPanel);
            app.TagTypeDropDownLabel.Text = 'Tag Type';
            app.TagTypeDropDown = uidropdown(app.TagtoolPanel);
            app.TagTypeDropDown.Items = {'1', '2', '3', '4', '5'};
            app.TagTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TagTypeDropDownValueChanged, true);
            app.TagTypeDropDown.Value = '1';
            app.AddButton = uibutton(app.TagtoolPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.EditButton = uibutton(app.TagtoolPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DeleteButton = uibutton(app.TagtoolPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.UITable_Tag = uitable(app.TagtoolPanel);
            app.UITable_Tag.ColumnName = {'Number'; 'Tag Type'; 'Time'; 'ROI number'};
            app.UITable_Tag.RowName = {};
            app.UITable_Tag.ColumnSortable = true;
            app.UITable_Tag.ColumnEditable = true;
            app.ColorDropDownLabel = uilabel(app.TagtoolPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.TagtoolPanel);
            app.ColorDropDown.Items = {'Blue', 'Green', 'Red', 'Grey', 'Magenta', 'Cyan', 'Yellow'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'Blue';
            app.SaveTagButton = uibutton(app.TagtoolPanel, 'push');
            app.SaveTagButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTagButtonPushed, true);
            app.SaveTagButton.Text = 'Save Tag';
            app.AdditionOptionPanel = uipanel(app.UIFigure);
            app.AdditionOptionPanel.Title = 'Addition Option';
            app.MarkerCheckBox = uicheckbox(app.AdditionOptionPanel);
            app.MarkerCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarkerCheckBoxValueChanged, true);
            app.MarkerCheckBox.Text = 'Marker';
            app.TriggerCheckBox = uicheckbox(app.AdditionOptionPanel);
            app.TriggerCheckBox.ValueChangedFcn = createCallbackFcn(app, @TriggerCheckBoxValueChanged, true);
            app.TriggerCheckBox.Text = 'Trigger';
            app.TriggerCheckBox.Value = true;
            app.TimesSliderLabel = uilabel(app.UIFigure);
            app.TimesSliderLabel.Text = 'Time (s)';
            app.TimesSlider = uislider(app.UIFigure);
            app.TimesSlider.ValueChangedFcn = createCallbackFcn(app, @TimesSliderValueChanged, true);
            app.TimesSlider.ValueChangingFcn = createCallbackFcn(app, @TimesSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fiber_photometry_video_sync_tag_app_V1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end